classdef Ai_Material_Selection_BiggerPrototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        selectionTab                    matlab.ui.container.Tab
        req                             matlab.ui.control.TextArea
        CarrimrequrementsPropertiesTextAreaLabel  matlab.ui.control.Label
        TopMaterialsListBox             matlab.ui.control.ListBox
        TopRankedMaterialsLabel         matlab.ui.control.Label
        DesisionmaterixwithweightsLabel  matlab.ui.control.Label
        CriteriaTable                   matlab.ui.control.Table
        SelectthecartypeyoudesireLabel  matlab.ui.control.Label
        RankMaterials                   matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        CurveAxes                       matlab.ui.control.UIAxes
        screeningandrankingTab          matlab.ui.container.Tab
        RankingLabel                    matlab.ui.control.Label
        ScreeningLabel                  matlab.ui.control.Label
        RankingMathTable                matlab.ui.control.Table
        ScreeningTable                  matlab.ui.control.Table
        materialsAshbychartTab          matlab.ui.container.Tab
        AshbyAxes                       matlab.ui.control.UIAxes
        GeneralAshbychartTab            matlab.ui.container.Tab
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        manufacturingTab                matlab.ui.container.Tab
        LoadsStressesTextArea           matlab.ui.control.TextArea
        LoadsstressesTextAreaLabel      matlab.ui.control.Label
        MfgTextArea                     matlab.ui.control.TextArea
        ManufacturingProcessConstraintsLabel  matlab.ui.control.Label
        RimAxes                         matlab.ui.control.UIAxes
        custominputsTab                 matlab.ui.container.Tab
        DensitySliderLabel              matlab.ui.control.Label
        StrengthSliderLabel             matlab.ui.control.Label
        StiffnessSliderLabel            matlab.ui.control.Label
        FatigueSliderLabel              matlab.ui.control.Label
        AvailabilitySlider              matlab.ui.control.Slider
        AvailabilitySliderLabel         matlab.ui.control.Label
        TorqueSliderLabel               matlab.ui.control.Label
        ImpactSlider                    matlab.ui.control.Slider
        ImpactSliderLabel               matlab.ui.control.Label
        CorrosionSlider                 matlab.ui.control.Slider
        newcustomrimLabel               matlab.ui.control.Label
        MaxCorneringGForceEditField     matlab.ui.control.NumericEditField
        MaxCorneringGForceEditFieldLabel  matlab.ui.control.Label
        VehicleWeightinkgEditField      matlab.ui.control.NumericEditField
        CorrosionSliderLabel            matlab.ui.control.Label
        DensitySlider                   matlab.ui.control.Slider
        StrengthSlider                  matlab.ui.control.Slider
        StiffnessSlider                 matlab.ui.control.Slider
        CustomSlidersValueChanging      matlab.ui.control.Slider
        CostSlider                      matlab.ui.control.Slider
        CostSliderLabel                 matlab.ui.control.Label
        VehicleWeightinkgEditFieldLabel  matlab.ui.control.Label
        LateralLoadLabel                matlab.ui.control.TextArea
        TorqueSlider                    matlab.ui.control.Slider
        calculatedlateralforceTextAreaLabel  matlab.ui.control.Label
        RadialLoadLabel                 matlab.ui.control.TextArea
        calculatedradialforceTextAreaLabel  matlab.ui.control.Label
        CriteriaTablenew                matlab.ui.control.Table
        CustomWeightCheckBox            matlab.ui.control.CheckBox
        startcalculationButton          matlab.ui.control.Button
        calcualtion                     matlab.ui.control.TextArea
        calcualtionTextAreaLabel        matlab.ui.control.Label
        Density                         matlab.ui.control.NumericEditField
        enterthedensityEditFieldLabel   matlab.ui.control.Label
        YieldStrength                   matlab.ui.control.NumericEditField
        entertheyieldstrengthEditFieldLabel  matlab.ui.control.Label
        calculatetheindexLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
         RimSurface % Controls the 3D rim
        AshbyScatter % Controls the scatter plot colors
        MaterialData % Holds the CSV database 
        PassedIndices % to identify if material passed or failed
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    % In startupFcn, replace the cylinder() block with:
    TR = stlread('rim.STL');
    app.RimSurface = trisurf(TR, 'Parent', app.RimAxes, 'EdgeColor', 'none', 'FaceColor', [0.7 0.7 0.7]);
    camlight(app.RimAxes, 'headlight');
    lighting(app.RimAxes, 'gouraud');
    axis(app.RimAxes, 'equal', 'off');
    title(app.RimAxes, '3D Rim Visualization');

    % Load the material database into memory
            app.MaterialData = readtable('MaterialDatabase.csv');
            
            % Populate the List Box with all 40 materials automatically
            app.TopMaterialsListBox.Items = app.MaterialData.MaterialName;
            
            % Initialize the Ashby Chart
            cla(app.AshbyAxes);
            app.AshbyScatter = scatter(app.AshbyAxes, app.MaterialData.Density_gcm3, app.MaterialData.Yield_MPa, 100, [0 0.4470 0.7410], 'filled');
            
            % =========================================================
            % NEW: ADD INTERACTIVE HOVER LABELS (DATA TIPS)
            % =========================================================
            % 1. Create custom rows for the hover pop-up box
            nameRow = dataTipTextRow('Material', app.MaterialData.MaterialName');
            densityRow = dataTipTextRow('Density (g/cm^3)', app.MaterialData.Density_gcm3');
            yieldRow = dataTipTextRow('Yield (MPa)', app.MaterialData.Yield_MPa');
            
            % 2. Apply these rows to the scatter plot's template
            app.AshbyScatter.DataTipTemplate.DataTipRows = [nameRow; densityRow; yieldRow];
            % =========================================================
            
            xlabel(app.AshbyAxes, 'Density (g/cm^3)', 'FontWeight', 'bold');
            ylabel(app.AshbyAxes, 'Yield Strength (MPa)', 'FontWeight', 'bold');
            title(app.AshbyAxes, 'Ashby Chart: Strength vs. Density');
            grid(app.AshbyAxes, 'on');

        end

        % Value changed function: MfgTextArea
        function MfgTextAreaValueChanged(app, event)

        end

        % Value changed function: RankMaterials
        function RankMaterialsValueChanged(app, event)
% 1. Set the definitions text
            loadsText = [ ...
                "Radial Load: Static weight of vehicle and passengers"; ...
                "Lateral Load: Extreme forces during highspeed cornering"; ...
                "Impact Load: Dynamic stress from potholes"; ...
                "Yield Strength: Must hold the vehicle static load"; ...
                "Fatigue: Constant cyclic load resistance"; ...
                "Fracture Toughness: Prevent rapid crack propagation" ...
            ];
            reqText = [ ...
                "Function: Support vehicle load, transmit torque, and withstand high-velocity impacts"; ...
                "Constraints: High fatigue resistance, high stiffness, and superior corrosion resistance"; ...
                "Objectives: Minimize weight to improve acceleration and maximize fuel/battery efficiency"; ...
                "Free Variables: Selection of Material Type and Optimization of Spoke Geometry."; ...
                "Properties:"; ...
                "1) High Fatigue resistance"; ...
                "2) Corrosion Resistance ";...
                "3) High Fracture Toughness";...
                "4) High yield strength ";...
                "5) Ductility ";...
            ];
            app.LoadsStressesTextArea.Value = loadsText;
            app.req.Value=reqText;
            % 2. Get the current weights from your sliders or presets (Now with 8 items)
            carType = app.RankMaterials.Value;
            
            if strcmp(carType, 'Custom')
                % Added AvailabilitySlider
                rawWeights = [app.CustomSlidersValueChanging.Value; app.StiffnessSlider.Value; app.StrengthSlider.Value; app.DensitySlider.Value; app.CostSlider.Value; app.CorrosionSlider.Value; app.ImpactSlider.Value; app.AvailabilitySlider.Value];
                if sum(rawWeights) == 0; weights = ones(8,1)/8; else; weights = rawWeights / sum(rawWeights); end
            
            % Presets updated to sum to 1.0 with Availability included!
            elseif contains(carType, 'Normal', 'IgnoreCase', true); weights = [0.15; 0.10; 0.10; 0.15; 0.20; 0.10; 0.10; 0.10];
            elseif contains(carType, 'Luxury', 'IgnoreCase', true); weights = [0.15; 0.20; 0.10; 0.15; 0.05; 0.15; 0.10; 0.10];
            elseif contains(carType, 'Sport', 'IgnoreCase', true);  weights = [0.25; 0.15; 0.15; 0.25; 0.05; 0.05; 0.05; 0.05];
            elseif contains(carType, 'EV', 'IgnoreCase', true);     weights = [0.15; 0.15; 0.10; 0.25; 0.10; 0.10; 0.10; 0.05];
            else; weights = zeros(8, 1);
            end

            % =========================================================
            % TABLE 1: UPDATE THE WEIGHTS TABLE 
            % =========================================================
            propsList = {'Fatigue'; 'Stiffness'; 'Strength'; 'Density'; 'Cost'; 'Corrosion'; 'Impact'; 'Availability'};
            app.CriteriaTable.Data = table(propsList, (weights * 100), 'VariableNames', {'Property', 'Weight (%)'});


            % 3. Get the list of materials that PASSED the load test
            % =========================================================
            % 3. ADVANCED ENGINEERING SCREENING (Hard Constraints)
            % =========================================================
            % Read user inputs directly to calculate the active load
            mass = app.VehicleWeightinkgEditField.Value;
            gForce = app.MaxCorneringGForceEditField.Value;
            
            % Calculate active stress with a Safety Factor of 2.0
            radialForce = (mass * 9.81) / 4; 
            lateralForce = mass * gForce * 9.81; 
            maxAppliedStress = (radialForce + lateralForce) / 1500; % Assuming 1500mm^2 area
            calculatedRequiredYield = maxAppliedStress * 2.0;
            
            % DEFINE ABSOLUTE BASELINE MINIMUMS FOR ANY CAR RIM
            baselineYield = 160; % MPa (Eliminates Bronze Alloy and very weak plastics)
            minModulus = 40000;  % MPa (Eliminates highly flexible polymers like ABS, Nylon, Polycarbonate)
            minImpact = 4;       % Score (Eliminates extremely brittle/shattering materials like Titanium Aluminide)
            
            % The required yield is whichever is higher: the baseline, or the user's load
            activeRequiredYield = max(baselineYield, calculatedRequiredYield);
            
            numTotalMaterials = height(app.MaterialData);
            statusArray = strings(numTotalMaterials, 1);
            
            % PROFESSIONAL FIX: Pre-allocate the array to prevent speed warnings!
            validIndices = zeros(numTotalMaterials, 1); 
            passCount = 0; % Keep track of how many actually pass
            
            for i = 1:numTotalMaterials
                matYield = app.MaterialData.Yield_MPa(i);
                matMod = app.MaterialData.Modulus_MPa(i);
                matImpact = app.MaterialData.Impact_Score(i);
                
                % Filter out materials based on hard engineering constraints!
                if matMod < minModulus
                    statusArray(i) = "FAIL (Too Flexible)";
                elseif matYield < activeRequiredYield
                    statusArray(i) = "FAIL (Too Weak)";
                elseif matImpact < minImpact
                    statusArray(i) = "FAIL (Too Brittle)";
                else
                    statusArray(i) = "PASS";
                    
                    % Add the passing material safely without warnings
                    passCount = passCount + 1;
                    validIndices(passCount) = i; 
                end
            end
            
            % Chop off any empty slots that we didn't use!
            validIndices = validIndices(1:passCount);
            
            % =========================================================
            % TABLE 2: UPDATE THE PASS/FAIL TABLE 
            % =========================================================
            app.ScreeningTable.Data = table(app.MaterialData.MaterialName, app.MaterialData.Yield_MPa, statusArray, ...
                'VariableNames', {'Material', 'Yield (MPa)', 'Status'});
            % =========================================================
            % APPLY COLORS TO THE SCREENING TABLE
            % =========================================================
            % 1. Clear any old colors from previous runs
            removeStyle(app.ScreeningTable); 
            
            % 2. Create the "Highlighter" Styles (Soft background, dark text)
            passStyle = uistyle('BackgroundColor', [0.85 1.00 0.85], 'FontColor', [0 0.5 0], 'FontWeight', 'bold'); % Green
            failStyle = uistyle('BackgroundColor', [1.00 0.85 0.85], 'FontColor', [0.7 0 0]); % Red
            
            % 3. Find the exact row numbers for passes and fails
            passRows = find(statusArray == "PASS");
            failRows = find(contains(statusArray, "FAIL"));
            
            % 4. Apply the styles to the entire row
            if ~isempty(passRows)
                addStyle(app.ScreeningTable, passStyle, 'row', passRows);
            end
            
            if ~isempty(failRows)
                addStyle(app.ScreeningTable, failStyle, 'row', failRows);
            end
            % If NO materials passed, clear the ranking UI and stop
            if isempty(validIndices)
                app.TopMaterialsListBox.Items = {'ALL MATERIALS FAILED THE LOAD TEST'};
                app.RankingMathTable.Data = [];
                return;
            end


            % 4. Calculate Scores ONLY for materials that passed
            numValid = length(validIndices);
            finalScores = zeros(numValid, 1);
            
            % Arrays to hold math data (Now 9 columns: 8 criteria + 1 final score)
            mathNames = strings(numValid, 1);
            mathScores = zeros(numValid, 9); 
            
            maxYield = max(app.MaterialData.Yield_MPa);
            maxDensity = max(app.MaterialData.Density_gcm3);
            minDensity = min(app.MaterialData.Density_gcm3);
            
            for k = 1:numValid
                i = validIndices(k); % Get the actual row number from the CSV
                
                % Normalize physical properties
                normYield = (app.MaterialData.Yield_MPa(i) / maxYield) * 10;
                normDensity = 10 - (((app.MaterialData.Density_gcm3(i) - minDensity) / (maxDensity - minDensity)) * 9);
                
                % Extract scores from CSV
                f = app.MaterialData.Fatigue_Score(i);
                st = app.MaterialData.Stiffness_Score(i);
                cst = app.MaterialData.Cost_Score(i); % NOTE: 10 = Cheapest, 1 = Most Expensive!
                cor = app.MaterialData.Corrosion_Score(i);
                imp = app.MaterialData.Impact_Score(i);
                avail = app.MaterialData.Availability_Score(i); % NEW AVAILABILITY SCORE
                
                % WSM Math (Adding Availability to the formula)
                score = (f*weights(1)) + (st*weights(2)) + (normYield*weights(3)) + (normDensity*weights(4)) + (cst*weights(5)) + (cor*weights(6)) + (imp*weights(7)) + (avail*weights(8));
                finalScores(k) = score;
                
                % Save data for the Transparency Table
                mathNames(k) = app.MaterialData.MaterialName{i};
                mathScores(k, :) = [f, st, normYield, normDensity, cst, cor, imp, avail, score];
            end
            
            % 5. Sort and Extract Top 10
            [sortedScores, sortOrder] = sort(finalScores, 'descend');
            topCount = min(10, numValid);
            topIndices = sortOrder(1:topCount);
            
            listBoxItems = strings(topCount, 1);
            for k = 1:topCount
                origIndex = validIndices(topIndices(k));
                listBoxItems(k) = sprintf('%d. %s (Score: %.1f/10)', k, app.MaterialData.MaterialName{origIndex}, sortedScores(k));
            end
            
            % Update the List Box
            app.TopMaterialsListBox.Items = listBoxItems;
            
            % =========================================================
            % TABLE 3: UPDATE THE MATH TABLE 
            % =========================================================
            app.RankingMathTable.Data = table(mathNames, mathScores(:,1), mathScores(:,2), mathScores(:,3), mathScores(:,4), mathScores(:,5), mathScores(:,6), mathScores(:,7), mathScores(:,8), mathScores(:,9), ...
                'VariableNames', {'Material', 'Fatigue', 'Stiffness', 'Strength', 'Density', 'Cost', 'Corrosion', 'Impact', 'Availability', 'FINAL SCORE'});
            
        end

        % Value changed function: CustomWeightCheckBox
        function CustomWeightCheckBoxValueChanged(app, event)
            value = app.CustomWeightCheckBox.Value;
    if value == true
        app.RankMaterials.Value = 'Custom';
        % Enable sliders
        app.CustomSlidersValueChanging.Enable = 'on';
        app.StiffnessSlider.Enable = 'on';
        app.StrengthSlider.Enable = 'on';
        app.DensitySlider.Enable = 'on';
        app.CostSlider.Enable = 'on';
        app.CorrosionSlider.Enable = 'on';
        app.ImpactSlider.Enable = 'on';
        app.TorqueSlider.Enable = 'on';
        app.AvailabilitySlider.Enable = 'on';
        % (Enable all other sliders...)
        
    else
        % Disable sliders
        app.CustomSlidersValueChanging.Enable = 'off';
        app.StiffnessSlider.Enable = 'off';
        app.StrengthSlider.Enable = 'off';
        app.DensitySlider.Enable = 'off';
        app.CostSlider.Enable = 'off';
        app.CorrosionSlider.Enable = 'off';
        app.ImpactSlider.Enable = 'off';
        app.TorqueSlider.Enable = 'off';
        app.AvailabilitySlider.Enable = 'off';
        % (Disable all other sliders...)
    end
        end

        % Value changed function: RadialLoadLabel
        function RadialLoadLabelValueChanged(app, event)

        end

        % Value changed function: LateralLoadLabel
        function LateralLoadLabelValueChanged(app, event)

        end

        % Value changed function: TopMaterialsListBox
        function TopMaterialsListBoxValueChanged(app, event)
            selectedText = string(app.TopMaterialsListBox.Value);
            
            if isempty(selectedText) || selectedText == ""
                return;
            end
            
            % Extract just the material name (ignoring the "1." and "Score")
            % This splits the string by spaces and recombines the middle parts
            parts = split(selectedText, ' ');
            matNameStr = strjoin(parts(2:end-2), ' '); 
            
            % Find this material in the database
            idx = find(strcmpi(app.MaterialData.MaterialName, matNameStr));
            
            if isempty(idx)
                return;
            end
            
            % 1. Display Manufacturing Data
            mfgInfo = sprintf('%s\nProcess: %s\nConstraints: %s', ...
                app.MaterialData.MaterialName{idx}, ...
                app.MaterialData.MfgProcess{idx}, ...
                app.MaterialData.Constraints{idx});
            app.MfgTextArea.Value = {mfgInfo};
            
            % ---------------------------------------------------------
            % 3. GENERATE STRESS-STRAIN CURVE (Hybrid Model + Database)
            % ---------------------------------------------------------
            if ~isempty(idx)
                % 1. Pull properties dynamically from the CSV Database
                Yield = app.MaterialData.Yield_MPa(idx);
                E = app.MaterialData.Modulus_MPa(idx);
                UTS = app.MaterialData.UTS_MPa(idx);
                Category = app.MaterialData.Category{idx};
                matName = app.MaterialData.MaterialName{idx};

                % Clear axes and hold
                cla(app.CurveAxes); 
                hold(app.CurveAxes, 'on');

                % 2. Identify Brittle vs Ductile using the CSV 'Category' column!
                if strcmpi(Category, 'Composite') || strcmpi(Category, 'Polymer')
                    % --- BRITTLE COMPOSITE/POLYMER MODEL ---
                    % Linear elastic all the way to catastrophic failure
                    stress = linspace(0, UTS, 50);
                    strain = stress ./ E; % Hooke's Law only
                    
                    plot(app.CurveAxes, strain, stress, 'LineWidth', 2.5, 'Color', [0.2 0.2 0.2]); % Dark Gray
                    
                    % Mark Fracture Point
                    scatter(app.CurveAxes, max(strain), max(stress), 100, 'red', 'x', 'LineWidth', 2);
                    text(app.CurveAxes, max(strain)*0.7, max(stress)*1.05, 'Brittle Fracture', 'Color', 'red', 'FontWeight', 'bold');
                    
                else
                    % --- OLD DUCTILE METAL MODEL (Generic Parabola) ---
                    
                    % Estimate Fracture Strain for visual plotting (Yield strain + 10% elongation)
                    yieldStrain = Yield / E;
                    failStrain = yieldStrain + 0.10; 
                    
                    % Safety check: UTS must be higher than Yield for a parabola to draw correctly
                    if UTS <= Yield
                        UTS = Yield * 1.05; 
                    end

                    % Calculate Elastic Region (Linear Hooke's Law)
                    strainElastic = linspace(0, yieldStrain, 20);
                    stressElastic = strainElastic * E;

                    % Calculate Plastic Region (Parabolic Approximation)
                    strainPlastic = linspace(yieldStrain, failStrain, 50);
                    stressPlastic = Yield + (UTS - Yield) * sqrt((strainPlastic - yieldStrain) / (failStrain - yieldStrain));

                    % Combine arrays for plotting
                    totalStrain = [strainElastic, strainPlastic];
                    totalStress = [stressElastic, stressPlastic];

                    % Plot the Curve
                    plot(app.CurveAxes, totalStrain, totalStress, 'LineWidth', 2.5, 'Color', [0.8500 0.3250 0.0980]); % Orange-Red line
                    
                    % 1. Plot the Blue Dot at the Yield Point
                    scatter(app.CurveAxes, yieldStrain, Yield, 80, 'blue', 'filled'); 
                    
                    % 2. Add the Name Label next to it with the highly visible white box
                    text(app.CurveAxes, yieldStrain * 1.15, Yield, 'Yield Point', ...
                        'FontSize', 10, ...
                        'FontWeight', 'bold', ...
                        'Color', 'blue', ...
                        'BackgroundColor', 'white', ...
                        'EdgeColor', 'blue', ...
                        'Margin', 2);
                    
                    % Mark Fracture Point
                    scatter(app.CurveAxes, failStrain, UTS, 100, 'black', 'x', 'LineWidth', 2);
                    text(app.CurveAxes, failStrain*0.85, UTS*1.05, 'Ductile Fracture', 'FontWeight', 'bold');
                end

                % Format the Graph dynamically
                hold(app.CurveAxes, 'off');
                title(app.CurveAxes, sprintf('Simulated Tensile Test:\n%s', matName));
                xlabel(app.CurveAxes, 'Strain (\epsilon) [mm/mm]', 'FontWeight', 'bold');
                ylabel(app.CurveAxes, 'Stress (\sigma) [MPa]', 'FontWeight', 'bold');
                grid(app.CurveAxes, 'on');
                
                % Adjust axes limits so the graph always frames nicely
                if strcmpi(Category, 'Composite') || strcmpi(Category, 'Polymer')
                    xlim(app.CurveAxes, [0, max(strain) * 1.15]);
                else
                    xlim(app.CurveAxes, [0, failStrain * 1.15]);
                end
                ylim(app.CurveAxes, [0, UTS * 1.15]);
            end
            
            % 3. Highlight on Ashby Chart
            allColors = repmat([0 0.4470 0.7410], height(app.MaterialData), 1); 
            allColors(idx, :) = [1 0 0]; % Make selected dot red
            app.AshbyScatter.CData = allColors;


            % 1. Update the Text Area
            app.MfgTextArea.Value = mfgInfo;
            
            % 2. Update the Ashby Chart Colors
            if ~isempty(app.AshbyScatter) && isvalid(app.AshbyScatter)
                % Create an array of standard blue colors for ALL materials in the database dynamically
                numMaterials = height(app.MaterialData);
                allColors = repmat([0 0.4470 0.7410], numMaterials, 1); 
                
                % Change the specific clicked material (using 'idx', not 'selectedIndex') to Red
                if ~isempty(idx)
                    allColors(idx, :) = [1 0 0]; % RGB for Red
                end
                
                % Apply the new colors to the scatter plot
                app.AshbyScatter.CData = allColors;
            end
                % If a valid material was clicked, change its specific color to Red!
               
                
                % Apply the new colors to the scatter plot
                app.AshbyScatter.CData = allColors;


        end

        % Value changing function: calcualtion
        function calcualtionValueChanging(app, event)
           
        end

        % Button pushed function: startcalculationButton
        function startcalculationButtonPushed(app, event)
            Sigma = app.YieldStrength.Value;
            Rho = app.Density.Value;
            M = Sigma / Rho;
            
            outputText = {
                sprintf('index ratio (M) = σ/ρ = %.2f', M)
                
            };
            
            app.calcualtion.Value = outputText;

           
        end

        % Value changing function: AvailabilitySlider, CorrosionSlider, 
        % ...and 7 other components
        function CustomSlidersValueChangingValueChanging(app, event)
            % 1. Read all the current raw values from the sliders
            rawWeights = [
                app.CustomSlidersValueChanging.Value;
                app.StiffnessSlider.Value;
                app.StrengthSlider.Value;
                app.DensitySlider.Value;
                app.CostSlider.Value;
                app.CorrosionSlider.Value;
                app.ImpactSlider.Value;
                app.TorqueSlider.Value;
                app.AvailabilitySlider.Value
            ];

            % 2. Calculate the total sum of the raw slider positions
            totalSum = sum(rawWeights);

            % 3. Normalize the weights to exactly 100%
            % Formula: (Raw Value / Total Sum) * 100
            if totalSum == 0
                % Prevent division by zero error if all sliders are at 0
                normalizedWeights = zeros(9, 1);
            else
                normalizedWeights = (rawWeights / totalSum) * 100;
            end

            % 4. Instantly update the Criteria Table to show the true 100% breakdown
            properties = {'Fatigue'; 'Stiffness'; 'Strength'; 'Density'; 'Cost'; 'Corrosion'; 'Impact'; 'Torque'; 'Availability'};
            app.CriteriaTablenew.Data = table(properties, normalizedWeights, 'VariableNames', {'Property', 'Weight_Percent'});

        end

        % Value changed function: MaxCorneringGForceEditField, 
        % ...and 1 other component
        function CalculateLoadsValueChanged(app, event)
            % 1. Read the inputs
            mass = app.VehicleWeightinkgEditField.Value;
            gForce = app.MaxCorneringGForceEditField.Value;
            gravity = 9.81; % m/s^2
            
            % 2. Calculate forces
            radialForce = (mass * gravity) / 4; 
            lateralForce = mass * gForce * gravity; 
            
            % Format and send output to Text Areas
            app.RadialLoadLabel.Value = {sprintf('%.2f N', radialForce)};
            app.LateralLoadLabel.Value = {sprintf('%.2f N', lateralForce)};
            
            % 3. Calculate Required Strength (Safety Factor = 2.0)
            % Assuming min cross-sectional area of 1500 mm^2
            maxAppliedStress = (radialForce + lateralForce) / 1500; 
            requiredYieldStrength = maxAppliedStress * 2.0;
            
            % 4. SCREENING PROCESS
            numMaterials = height(app.MaterialData);
            statusArray = strings(numMaterials, 1);

           passedList = zeros(numMaterials, 1); % Pre-allocate max possible size
passCount = 0;

for i = 1:numMaterials
    if app.MaterialData.Yield_MPa(i) >= requiredYieldStrength
        statusArray(i) = "PASS";
        passCount = passCount + 1;
        passedList(passCount) = i; % No resizing, no warning
    else
        statusArray(i) = "FAIL (Too Weak)";
    end
end

passedList = passedList(1:passCount); % Trim unused slots
            
            % Save the survivors to the app property so RankMaterials can see it!
            app.PassedIndices = passedList;
            
            % 5. Print the transparency report to the Screening Table
            % reqArray = repmat(requiredYieldStrength, numMaterials, 1);
            app.ScreeningTable.Data = table(app.MaterialData.MaterialName, app.MaterialData.Yield_MPa,  statusArray, ...
                'VariableNames', {'Material', 'Yield (MPa)', 'Status'});

            % =========================================================
            % TABLE 2: UPDATE THE PASS/FAIL TABLE (Strictly 3 Columns)
            % =========================================================
            app.ScreeningTable.Data = table(app.MaterialData.MaterialName, app.MaterialData.Yield_MPa, statusArray, ...
                'VariableNames', {'Material', 'Yield (MPa)', 'Status'});

            % =========================================================
            % APPLY COLORS TO THE SCREENING TABLE
            % =========================================================
            % 1. Clear old colors to start fresh
            removeStyle(app.ScreeningTable); 
            
            % 2. Create the styles (Green for Pass, Red for Fail)
            passStyle = uistyle('BackgroundColor', [0.85 1.00 0.85], 'FontColor', [0 0.5 0], 'FontWeight', 'bold');
            failStyle = uistyle('BackgroundColor', [1.00 0.85 0.85], 'FontColor', [0.8 0 0], 'FontWeight', 'bold');
            
            % 3. Find the exact row numbers for passes and fails
            passRows = find(statusArray == "PASS");
            failRows = find(contains(statusArray, "FAIL"));
            
            % 4. Apply the styles to the entire rows
            if ~isempty(passRows)
                addStyle(app.ScreeningTable, passStyle, 'row', passRows);
            end
            
            if ~isempty(failRows)
                addStyle(app.ScreeningTable, failStyle, 'row', failRows);
            end
                
            % 6. Auto-Trigger the Ranking Engine now that screening is done!
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.selectionTab = uitab(app.TabGroup);
            app.selectionTab.Title = 'selection';
            app.CurveAxes = uiaxes(app.selectionTab);
            title(app.CurveAxes, 'Stress-Strain Curve')
            xlabel(app.CurveAxes, 'X')
            ylabel(app.CurveAxes, 'Y')
            zlabel(app.CurveAxes, 'Z')
            app.DropDownLabel = uilabel(app.selectionTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.RankMaterials = uidropdown(app.selectionTab);
            app.RankMaterials.Items = {'Select Car Type', 'Normal Car', 'Luxury Car', 'Sport Car', 'EV Car', 'Custom'};
            app.RankMaterials.ValueChangedFcn = createCallbackFcn(app, @RankMaterialsValueChanged, true);
            app.RankMaterials.Value = 'Select Car Type';
            app.SelectthecartypeyoudesireLabel = uilabel(app.selectionTab);
            app.SelectthecartypeyoudesireLabel.Text = 'Select the car type you desire:';
            app.CriteriaTable = uitable(app.selectionTab);
            app.CriteriaTable.ColumnName = {'Property'; 'Weight(%)'};
            app.CriteriaTable.RowName = {};
            app.DesisionmaterixwithweightsLabel = uilabel(app.selectionTab);
            app.DesisionmaterixwithweightsLabel.Text = 'Desision materix  with weights';
            app.TopRankedMaterialsLabel = uilabel(app.selectionTab);
            app.TopRankedMaterialsLabel.Text = 'Top Ranked Materials';
            app.TopMaterialsListBox = uilistbox(app.selectionTab);
            app.TopMaterialsListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
            app.TopMaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @TopMaterialsListBoxValueChanged, true);
            app.CarrimrequrementsPropertiesTextAreaLabel = uilabel(app.selectionTab);
            app.CarrimrequrementsPropertiesTextAreaLabel.Text = 'Car rim requrements&Properties';
            app.req = uitextarea(app.selectionTab);
            app.screeningandrankingTab = uitab(app.TabGroup);
            app.screeningandrankingTab.Title = 'screening and ranking';
            app.ScreeningTable = uitable(app.screeningandrankingTab);
            app.ScreeningTable.ColumnName = {'material'; 'Yield (MPa)'; 'pass/fail'};
            app.ScreeningTable.RowName = {};
            app.RankingMathTable = uitable(app.screeningandrankingTab);
            app.RankingMathTable.ColumnName = {'Material'; 'Fatigue'; 'Stiffness'; 'Strength'; 'Density'; 'Cost'; 'Corrosion'; 'Impact'; 'Availability'; 'FINAL SCORE'};
            app.RankingMathTable.RowName = {};
            app.ScreeningLabel = uilabel(app.screeningandrankingTab);
            app.ScreeningLabel.Text = 'Screening';
            app.RankingLabel = uilabel(app.screeningandrankingTab);
            app.RankingLabel.Text = 'Ranking';
            app.materialsAshbychartTab = uitab(app.TabGroup);
            app.materialsAshbychartTab.Title = 'materials Ashby chart';
            app.AshbyAxes = uiaxes(app.materialsAshbychartTab);
            title(app.AshbyAxes, 'Ashby Material chart')
            xlabel(app.AshbyAxes, 'Yield Strength (MPa)')
            ylabel(app.AshbyAxes, 'Young''s Modulus (GPa)')
            zlabel(app.AshbyAxes, 'Z')
            app.GeneralAshbychartTab = uitab(app.TabGroup);
            app.GeneralAshbychartTab.Title = 'General Ashby chart';
            app.Image = uiimage(app.GeneralAshbychartTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-05-02 220330.png');
            app.Image_2 = uiimage(app.GeneralAshbychartTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-05-02 220711.png');
            app.Image_3 = uiimage(app.GeneralAshbychartTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-05-02 220802.png');
            app.Image_4 = uiimage(app.GeneralAshbychartTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-05-02 220831.png');
            app.manufacturingTab = uitab(app.TabGroup);
            app.manufacturingTab.Title = 'manufacturing';
            app.RimAxes = uiaxes(app.manufacturingTab);
            title(app.RimAxes, 'Rim')
            xlabel(app.RimAxes, 'X')
            ylabel(app.RimAxes, 'Y')
            zlabel(app.RimAxes, 'Z')
            app.ManufacturingProcessConstraintsLabel = uilabel(app.manufacturingTab);
            app.ManufacturingProcessConstraintsLabel.Text = 'Manufacturing Process & Constraints';
            app.MfgTextArea = uitextarea(app.manufacturingTab);
            app.MfgTextArea.ValueChangedFcn = createCallbackFcn(app, @MfgTextAreaValueChanged, true);
            app.LoadsstressesTextAreaLabel = uilabel(app.manufacturingTab);
            app.LoadsstressesTextAreaLabel.Text = 'Loads & stresses';
            app.LoadsStressesTextArea = uitextarea(app.manufacturingTab);
            app.custominputsTab = uitab(app.TabGroup);
            app.custominputsTab.Title = 'custom inputs';
            app.calculatetheindexLabel = uilabel(app.custominputsTab);
            app.calculatetheindexLabel.Text = 'calculate the index';
            app.entertheyieldstrengthEditFieldLabel = uilabel(app.custominputsTab);
            app.entertheyieldstrengthEditFieldLabel.Text = 'enter the yield strength ';
            app.YieldStrength = uieditfield(app.custominputsTab, 'numeric');
            app.enterthedensityEditFieldLabel = uilabel(app.custominputsTab);
            app.enterthedensityEditFieldLabel.Text = 'enter the density';
            app.Density = uieditfield(app.custominputsTab, 'numeric');
            app.calcualtionTextAreaLabel = uilabel(app.custominputsTab);
            app.calcualtionTextAreaLabel.Text = 'calcualtion';
            app.calcualtion = uitextarea(app.custominputsTab);
            app.calcualtion.ValueChangingFcn = createCallbackFcn(app, @calcualtionValueChanging, true);
            app.startcalculationButton = uibutton(app.custominputsTab, 'push');
            app.startcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @startcalculationButtonPushed, true);
            app.startcalculationButton.Text = 'start calculation';
            app.CustomWeightCheckBox = uicheckbox(app.custominputsTab);
            app.CustomWeightCheckBox.ValueChangedFcn = createCallbackFcn(app, @CustomWeightCheckBoxValueChanged, true);
            app.CustomWeightCheckBox.Text = 'Enable Custom Weights';
            app.CriteriaTablenew = uitable(app.custominputsTab);
            app.CriteriaTablenew.ColumnName = {'Column 1'; 'Column 2'};
            app.CriteriaTablenew.RowName = {};
            app.calculatedradialforceTextAreaLabel = uilabel(app.custominputsTab);
            app.calculatedradialforceTextAreaLabel.Text = 'calculated radial force';
            app.RadialLoadLabel = uitextarea(app.custominputsTab);
            app.RadialLoadLabel.ValueChangedFcn = createCallbackFcn(app, @RadialLoadLabelValueChanged, true);
            app.calculatedlateralforceTextAreaLabel = uilabel(app.custominputsTab);
            app.calculatedlateralforceTextAreaLabel.Text = 'calculated lateral force';
            app.TorqueSlider = uislider(app.custominputsTab);
            app.TorqueSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.TorqueSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.LateralLoadLabel = uitextarea(app.custominputsTab);
            app.LateralLoadLabel.ValueChangedFcn = createCallbackFcn(app, @LateralLoadLabelValueChanged, true);
            app.VehicleWeightinkgEditFieldLabel = uilabel(app.custominputsTab);
            app.VehicleWeightinkgEditFieldLabel.Text = 'Vehicle Weight in kg';
            app.CostSliderLabel = uilabel(app.custominputsTab);
            app.CostSliderLabel.Text = 'Cost';
            app.CostSlider = uislider(app.custominputsTab);
            app.CostSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CostSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.CustomSlidersValueChanging = uislider(app.custominputsTab);
            app.CustomSlidersValueChanging.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CustomSlidersValueChanging.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.StiffnessSlider = uislider(app.custominputsTab);
            app.StiffnessSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StiffnessSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.StrengthSlider = uislider(app.custominputsTab);
            app.StrengthSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.StrengthSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.DensitySlider = uislider(app.custominputsTab);
            app.DensitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.DensitySlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.CorrosionSliderLabel = uilabel(app.custominputsTab);
            app.CorrosionSliderLabel.Text = 'Corrosion';
            app.VehicleWeightinkgEditField = uieditfield(app.custominputsTab, 'numeric');
            app.VehicleWeightinkgEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateLoadsValueChanged, true);
            app.MaxCorneringGForceEditFieldLabel = uilabel(app.custominputsTab);
            app.MaxCorneringGForceEditFieldLabel.Text = 'Max Cornering G-Force';
            app.MaxCorneringGForceEditField = uieditfield(app.custominputsTab, 'numeric');
            app.MaxCorneringGForceEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateLoadsValueChanged, true);
            app.newcustomrimLabel = uilabel(app.custominputsTab);
            app.newcustomrimLabel.Text = 'new custom rim';
            app.CorrosionSlider = uislider(app.custominputsTab);
            app.CorrosionSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CorrosionSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.ImpactSliderLabel = uilabel(app.custominputsTab);
            app.ImpactSliderLabel.Text = 'Impact';
            app.ImpactSlider = uislider(app.custominputsTab);
            app.ImpactSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ImpactSlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.TorqueSliderLabel = uilabel(app.custominputsTab);
            app.TorqueSliderLabel.Text = 'Torque';
            app.AvailabilitySliderLabel = uilabel(app.custominputsTab);
            app.AvailabilitySliderLabel.Text = 'Availability';
            app.AvailabilitySlider = uislider(app.custominputsTab);
            app.AvailabilitySlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.AvailabilitySlider.ValueChangingFcn = createCallbackFcn(app, @CustomSlidersValueChangingValueChanging, true);
            app.FatigueSliderLabel = uilabel(app.custominputsTab);
            app.FatigueSliderLabel.Text = 'Fatigue';
            app.StiffnessSliderLabel = uilabel(app.custominputsTab);
            app.StiffnessSliderLabel.Text = 'Stiffness';
            app.StrengthSliderLabel = uilabel(app.custominputsTab);
            app.StrengthSliderLabel.Text = 'Strength';
            app.DensitySliderLabel = uilabel(app.custominputsTab);
            app.DensitySliderLabel.Text = 'Density';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ai_Material_Selection_BiggerPrototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end